classdef SeniorApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        BzGSlider                   matlab.ui.control.Slider
        BzGSliderLabel              matlab.ui.control.Label
        ByGSlider                   matlab.ui.control.Slider
        ByGSliderLabel              matlab.ui.control.Label
        BxGSlider                   matlab.ui.control.Slider
        BxGSliderLabel              matlab.ui.control.Label
        EXTERNALMAGNETICFIELDLabel  matlab.ui.control.Label
        TabGroup                    matlab.ui.container.TabGroup
        SingleProtonTab             matlab.ui.container.Tab
        ClearButton                 matlab.ui.control.Button
        PlotButton_2                matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
        BulkMagnetizationTab        matlab.ui.container.Tab
        EditField2                  matlab.ui.control.NumericEditField
        EditField                   matlab.ui.control.NumericEditField
        T2msSlider                  matlab.ui.control.Slider
        T2msSliderLabel             matlab.ui.control.Label
        T1msSlider                  matlab.ui.control.Slider
        T1msSliderLabel             matlab.ui.control.Label
        PlotButton                  matlab.ui.control.Button
        UIAxes3_2                   matlab.ui.control.UIAxes
        UIAxes3                     matlab.ui.control.UIAxes
        AnimationTab                matlab.ui.container.Tab
        MagnetizationValuesLabel    matlab.ui.control.Label
        T225msLabel                 matlab.ui.control.Label
        T125msLabel                 matlab.ui.control.Label
        ClearButton_2               matlab.ui.control.Button
        AnimateButton               matlab.ui.control.Button
        UIAxes4                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton_2
        function PlotButton_2Pushed(app, event)
          % =================================================
          % This function plots the Single Proton Precession:
          % =================================================
          
          % External Magnetic Field:
          Bx = app.BxGSlider.Value;
          By = app.ByGSlider.Value;
          Bz = app.BzGSlider.Value;
          B_ext = [Bx, By, Bz];
          
          % Initializing Variables for Single Proton:
          Be = 0.5E-4; %T
          mu_p = 1; %(J/T)
          gamma = 2.675E8/1E3; % (rad/ms*T)
          w = gamma*norm(B_ext); % angular frequency (rad/ms)
          freq = w/(2*pi); %precession frequency of proton about B field (1/ms)
          T = 1/freq; %period of the proton's precession (ms)
          
          % Initial Conditions
          mu0 = mu_p*[sin(pi/3)*cos(pi/2); sin(pi/3)*sin(pi/2); cos(pi/3)];
          
          
          %% Numerically Solving for Magnetic Moment Components:
          f = @(t,u) [gamma*(u(2)*Bz - u(3)*By); ...
                      gamma*(u(3)*Bx - u(1)*Bz); ... 
                      gamma*(u(1)*By - u(2)*Bx)];
              
          tspan = linspace(0, T, 1E2);
          [t, u] = ode45(f, tspan, mu0);
          hold(app.UIAxes,'on')
          % Animated Plot
          curve1 = animatedline(app.UIAxes, 'LineWidth', 2);
          set(app.UIAxes, 'XLim', [-1 1], 'YLim', [-1 1], 'ZLim', [-0.5 1.5]);
          view(app.UIAxes,43,24); % Sets the angle of the view to see in 3D
          for i = 1:length(u)
                addpoints(curve1, u(i,1), u(i,2), u(i,3));
                head = scatter3(app.UIAxes, u(i,1), u(i,2), u(i,3),'filled', 'MarkerEdgeColor', 'k', 'MarkerFaceColor', [0 .75 .75]);
                % Magnetic Moment Vector as a Function of Time
                mu_vector = plot3(app.UIAxes, [0 u(i,1)], [0 u(i,2)], [0 u(i,3)], 'Color', 'red');
                drawnow
                delete(mu_vector);
                delete(head);
          end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
          % =================================================
          % This function plots the Bulk Magnetization:
          % =================================================
         
          % External Magnetic Field:
          Bx = app.BxGSlider.Value;
          By = app.ByGSlider.Value;
          Bz = app.BzGSlider.Value;
          B_ext = [Bx, By, Bz];
          
          %% Initializing Variables for Bulk Magnetization
          T1 = app.T1msSlider.Value; %s
          T2 = app.T2msSlider.Value; %s
          M0 = 1;
          tp = 400; % Five T1 Time Constants 
          gamma = 2.675E8/1E3; % (rad/ms*T)
          
          w = gamma*norm(B_ext); % angular frequency (rad/ms)
          freq = w/(2*pi); %precession frequency of proton about B field (1/ms)
          T = 1/freq; %period of the proton's precession (ms)
          dt = (1/w)*.01;
          
          %% Numerically Solving for Magnetization Components
          fM = @(t, M) ...
             gamma*[((M(2)*Bz) - (M(3)*By)); ...
                    ((M(3)*Bx) - (M(1)*Bz)); ...
                    ((M(1)*By) - (M(2)*Bx))] ...
                    - ([M(1), M(2), 0]/T2)' + ([0, 0, (M0 - M(3))]/T1)';
                        
          tt = linspace(0, tp, 1E5);
          
          % Initial conditions for magnetization 
          Mint = [0, M0, 0]; % Initial conditions for magnetization 
          
          [t, v] = ode45(fM, tt, Mint);
          set(app.UIAxes3_2, 'XLim', [-1 1], 'YLim', [-1 1], 'ZLim', [-1.5 1.5]);
          view(app.UIAxes3_2, 43,24); %Sets the angle of the view to see in 3D
          plot3(app.UIAxes3_2, v(:,1), v(:,2), v(:,3))
          
          % Plotting the Transverse Magnetization
          set(app.UIAxes3, 'XLim', [0 50], 'YLim', [-1 1]);
          plot(app.UIAxes3, t, v(:,1));


        end

        % Button pushed function: ClearButton, ClearButton_2
        function ClearButtonPushed(app, event)
            app.UIAxes.cla
            app.UIAxes4.cla
        end

        % Button pushed function: AnimateButton
        function AnimateButtonPushed(app, event)
          % =================================================
          % This function Animates the Bulk Magnetization:
          % =================================================
         
          % External Magnetic Field:
          Bx = 0;
          By = 0;
          Bz = 0.5E-4;
          
          gamma = 2.675E8/1E3;
          
          %% Initializing Variables for Bulk Magnetization
          T1 = 2.5; %s
          T2 = 2.5; %s
          M0 = 1;
          tp = 3; % Five T1 Time Constants 
          
          %% Numerically Solving for Magnetization Components
          fM = @(t, M) ...
             gamma*[((M(2)*Bz) - (M(3)*By)); ...
                    ((M(3)*Bx) - (M(1)*Bz)); ...
                    ((M(1)*By) - (M(2)*Bx))] ...
                    - ([M(1), M(2), 0]/T2)' + ([0, 0, (M0 - M(3))]/T1)';
                        
          tt = linspace(0, tp, 1E3);
          
          % Initial conditions for magnetization 
          Mint = [0, M0, 0]; % Initial conditions for magnetization 
          
          [t, v] = ode45(fM, tt, Mint);
          set(app.UIAxes4, 'XLim', [-1 1], 'YLim', [-1 1], 'ZLim', [-1.5 1.5]);
          view(app.UIAxes4, 43,24); %Sets the angle of the view to see in 3D
          
          curve2 = animatedline(app.UIAxes4, 'LineWidth', 2);
          
          hold(app.UIAxes4, 'on')
          for i = 1:length(v)
                addpoints(curve2, v(i,1), v(i,2), v(i,3));
                head2 = scatter3(app.UIAxes4, v(i,1), v(i,2), v(i,3),'filled', 'MarkerEdgeColor', 'k', 'MarkerFaceColor', [0 .75 .75]);
                M_vector = plot3(app.UIAxes4, [0 v(i,1)], [0 v(i,2)], [0 v(i,3)], 'Color', 'red', 'LineWidth', 3);
                
                % Drawing Magnetization Component Vectors 
                M_vector_x = plot3(app.UIAxes4, [v(i,1) 2*v(i,1)], [v(i,2) v(i,2)], [v(i,3) v(i,3)], 'Color', 'blue', 'LineWidth', 2);
                M_vector_y = plot3(app.UIAxes4, [v(i,1) v(i,1)], [v(i,2) 2*v(i,2)], [v(i,3) v(i,3)], 'Color', 'green', 'LineWidth', 2);
                M_vector_z = plot3(app.UIAxes4, [v(i,1) v(i,1)], [v(i,2) v(i,2)], [v(i,3) 2*v(i,3)], 'Color', 'black', 'LineWidth', 2);
                drawnow
                delete(M_vector)
                delete(M_vector_x)
                delete(M_vector_y)
                delete(M_vector_z)
                delete(head2);
          end  
          hold(app.UIAxes4, 'off')
        end

        % Value changed function: T1msSlider
        function T1msSliderValueChanged(app, event)
            value = app.T1msSlider.Value;
            
            app.EditField.Value = value;
        end

        % Value changed function: T2msSlider
        function T2msSliderValueChanged(app, event)
            value = app.T2msSlider.Value;
            
            app.EditField2.Value = value;
            
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            
            app.T1msSlider.Value = value;
        end

        % Value changed function: EditField2
        function EditField2ValueChanged(app, event)
            value = app.EditField2.Value;
            
            app.T2msSlider.Value = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SingleProtonTab = uitab(app.TabGroup);
            app.SingleProtonTab.Title = 'Single Proton';
            app.UIAxes = uiaxes(app.SingleProtonTab);
            title(app.UIAxes, 'Single Isolated Proton in a Constant Magnetic Field ')
            xlabel(app.UIAxes, '\mu_x')
            ylabel(app.UIAxes, '\mu_y')
            app.PlotButton_2 = uibutton(app.SingleProtonTab, 'push');
            app.PlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_2Pushed, true);
            app.PlotButton_2.Text = 'Plot';
            app.ClearButton = uibutton(app.SingleProtonTab, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.BulkMagnetizationTab = uitab(app.TabGroup);
            app.BulkMagnetizationTab.Title = 'Bulk Magnetization';
            app.UIAxes3 = uiaxes(app.BulkMagnetizationTab);
            title(app.UIAxes3, 'Transverse Magnetization')
            xlabel(app.UIAxes3, 'Time (ms)')
            ylabel(app.UIAxes3, {'M_y'; ''})
            app.UIAxes3_2 = uiaxes(app.BulkMagnetizationTab);
            title(app.UIAxes3_2, 'Bulk Magnetization')
            xlabel(app.UIAxes3_2, 'M_x')
            ylabel(app.UIAxes3_2, 'M_y')
            app.PlotButton = uibutton(app.BulkMagnetizationTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.T1msSliderLabel = uilabel(app.BulkMagnetizationTab);
            app.T1msSliderLabel.Text = 'T1 (ms)';
            app.T1msSlider = uislider(app.BulkMagnetizationTab);
            app.T1msSlider.Limits = [0 400];
            app.T1msSlider.ValueChangedFcn = createCallbackFcn(app, @T1msSliderValueChanged, true);
            app.T1msSlider.Value = 10;
            app.T2msSliderLabel = uilabel(app.BulkMagnetizationTab);
            app.T2msSliderLabel.Text = 'T2 (ms)';
            app.T2msSlider = uislider(app.BulkMagnetizationTab);
            app.T2msSlider.ValueChangedFcn = createCallbackFcn(app, @T2msSliderValueChanged, true);
            app.T2msSlider.Value = 5;
            app.EditField = uieditfield(app.BulkMagnetizationTab, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 2.5;
            app.EditField2 = uieditfield(app.BulkMagnetizationTab, 'numeric');
            app.EditField2.ValueChangedFcn = createCallbackFcn(app, @EditField2ValueChanged, true);
            app.EditField2.Value = 2.5;
            app.AnimationTab = uitab(app.TabGroup);
            app.AnimationTab.Title = 'Animation';
            app.UIAxes4 = uiaxes(app.AnimationTab);
            title(app.UIAxes4, 'Animated Bulk Magnetization')
            xlabel(app.UIAxes4, 'M_x')
            ylabel(app.UIAxes4, 'M_y')
            app.AnimateButton = uibutton(app.AnimationTab, 'push');
            app.AnimateButton.ButtonPushedFcn = createCallbackFcn(app, @AnimateButtonPushed, true);
            app.AnimateButton.Text = 'Animate';
            app.ClearButton_2 = uibutton(app.AnimationTab, 'push');
            app.ClearButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton_2.Text = 'Clear';
            app.T125msLabel = uilabel(app.AnimationTab);
            app.T125msLabel.Text = 'T1 = 2.5ms';
            app.T225msLabel = uilabel(app.AnimationTab);
            app.T225msLabel.Text = 'T2 = 2.5ms';
            app.MagnetizationValuesLabel = uilabel(app.AnimationTab);
            app.MagnetizationValuesLabel.Text = 'Magnetization Values';
            app.EXTERNALMAGNETICFIELDLabel = uilabel(app.UIFigure);
            app.EXTERNALMAGNETICFIELDLabel.Text = 'EXTERNAL MAGNETIC FIELD';
            app.BxGSliderLabel = uilabel(app.UIFigure);
            app.BxGSliderLabel.Text = 'Bx (G)';
            app.BxGSlider = uislider(app.UIFigure);
            app.BxGSlider.Limits = [0 0.0001];
            app.BxGSlider.MajorTicks = [0 2e-05 4e-05 6e-05 8e-05 0.0001];
            app.BxGSlider.MajorTickLabels = {'0', '.2', '.4', '.6', '.8', '1'};
            app.BxGSlider.MinorTicks = [];
            app.ByGSliderLabel = uilabel(app.UIFigure);
            app.ByGSliderLabel.Text = 'By (G)';
            app.ByGSlider = uislider(app.UIFigure);
            app.ByGSlider.Limits = [0 0.0001];
            app.ByGSlider.MajorTicks = [0 2e-05 4e-05 6e-05 8e-05 0.0001];
            app.ByGSlider.MajorTickLabels = {'0', '.2', '.4', '.6', '.8', '1'};
            app.ByGSlider.MinorTicks = [];
            app.BzGSliderLabel = uilabel(app.UIFigure);
            app.BzGSliderLabel.Text = 'Bz (G)';
            app.BzGSlider = uislider(app.UIFigure);
            app.BzGSlider.Limits = [0 0.0001];
            app.BzGSlider.MajorTicks = [0 2e-05 4e-05 6e-05 8e-05 0.0001];
            app.BzGSlider.MajorTickLabels = {'0', '.2', '.4', '.6', '.8', '1'};
            app.BzGSlider.MinorTicks = [];
            app.BzGSlider.Value = 5e-05;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SeniorApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end